classdef Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionanalysisUIFigure  matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        Label                   matlab.ui.control.Label
        ListBox                 matlab.ui.control.ListBox
        ListBox_2Label          matlab.ui.control.Label
        ListBox_2               matlab.ui.control.ListBox
        ParametersButton        matlab.ui.control.Button
        RunButton               matlab.ui.control.Button
        OptionsButton1          matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        OptionsButton3          matlab.ui.control.Button
        OptionsButton2          matlab.ui.control.Button
        OptionsButton6          matlab.ui.control.Button
        OptionsButton5          matlab.ui.control.Button
        OptionsButton4          matlab.ui.control.Button
        OptionsButton7          matlab.ui.control.Button
        DropDown                matlab.ui.control.DropDown
        LoadparametersButton    matlab.ui.control.Button
        OptionsButton8          matlab.ui.control.Button
        ListBox_3Label          matlab.ui.control.Label
        ListBox_3               matlab.ui.control.ListBox
    end


    properties (Access = private)
        OptionsIKApp % Description
        OptionsCalibIKApp
        OptionsIDApp
        OptionsCalibIDApp
        OptionsMusclesApp
        OptionsCalibMusclesApp
        OptionsPredictionApp
        OptionsGeneralApp
        OptionsExternalForcesApp
        Human_model
        Markers_set
        AnalysisParameters
        Human_model0
        Markers_set0
        path_figure
        extension
        ModelParameters
        AnimateParameters
    end

    methods (Access = public)
    
        function SaveParameters(app,NewAnalysisParameters,NewHuman_model,NewMarkers_set)
            app.AnalysisParameters = NewAnalysisParameters;
            app.Human_model = NewHuman_model;
            app.Markers_set = NewMarkers_set;
            app.UpdatePlot
        end
        
        function EnableButton1(app)
            app.OptionsButton1.Enable = 'on';
        end
        
        function EnableButton4(app)
            app.OptionsButton4.Enable = 'on';
        end
        
        function EnableButton2(app)
            app.OptionsButton2.Enable = 'on';
        end
        
        function EnableButton5(app)
            app.OptionsButton5.Enable = 'on';
        end
        
        function EnableButton3(app)
            app.OptionsButton3.Enable = 'on';
        end
        
        function EnableButton6(app)
            app.OptionsButton6.Enable = 'on';
        end
        
        function EnableButton7(app)
            app.OptionsButton7.Enable = 'on';
        end
        
        function ParametersButton1(app)
            app.ParametersButton.Enable = 'on';
        end
        
        function EnableButton8(app)
            app.OptionsButton8.Enable = 'on';
        end
        
    end

    methods (Access = private)
    
        function UpdatePlot(app)
            % Chargement des fichiers présents dans le fichier
            if isequal(app.AnalysisParameters.General.InputData, @MVNX_V3) || ...
                isequal(app.AnalysisParameters.General.InputData, @GetVirtualMarkers)                
                app.AnalysisParameters.General.Extension = '*.mvnx';
            elseif isequal(app.AnalysisParameters.General.InputData, @MatProcessedData)
                app.AnalysisParameters.General.Extension = '*.mat';
            else
                app.AnalysisParameters.General.Extension = '*.c3d';
            end
            list_files_c3d=dir(app.AnalysisParameters.General.Extension);
            app.ListBox.Items = {list_files_c3d.name};
            app.ListBox_2.Items = {list_files_c3d.name};
            app.ListBox_3.Items = {list_files_c3d.name};
            % If MVNX : no IK / no CalibIK
            app.AnalysisParameters.filename = app.ListBox.Value;
            if isequal(app.AnalysisParameters.General.InputData, @MVNX_V3)
%                 app.AnalysisParameters.IK.Active = false; % IK
                app.OptionsButton1.Enable = 'off';
                app.AnalysisParameters.CalibIK.Active = false; % CalibIK                
                app.OptionsButton4.Enable = 'off';
                app.AnalysisParameters.CalibID.Active = false; % CalibIK                
                app.OptionsButton5.Enable = 'off';
                app.AnalysisParameters.CalibMuscles.Active = false; % CalibIK                
                app.OptionsButton6.Enable = 'off';    
                app.AnalysisParameters.Muscles.Active = false; % Muscles               
                app.OptionsButton3.Enable = 'off';
                % No Plot Model
                cla(app.UIAxes2);
            else
                app.OptionsButton1.Enable = 'on';
                app.OptionsButton4.Enable = 'on';
                app.OptionsButton3.Enable = 'on';
                app.OptionsButton5.Enable = 'on';
                app.OptionsButton6.Enable = 'on';
                % Plot Model
                save_k_constraint = {app.Human_model.calib_k_constraint};
                save_v_constraint = {app.Human_model.v};
                save_calib_dir = {app.Markers_set.calib_dir};
                [app.Human_model,app.Markers_set] = ...
                    PlotAnimation(app.ModelParameters, app.AnimateParameters);
                for i = 1:numel(app.Human_model)
                    app.Human_model(i).calib_k_constraint = save_k_constraint{i};
                    app.Human_model(i).v = save_v_constraint{i};
                end
                for i = 1:numel(app.Markers_set)
                    app.Markers_set(i).calib_dir = save_calib_dir{i};
                end
            end
            % Active or not            
            if app.AnalysisParameters.ID.Active 
                app.DropDown.Visible = 'on';
            else
                app.DropDown.Visible = 'off';
                app.DropDown.Value = '0';
            end
            app.AnalysisParameters.ID.InputData = str2double(app.DropDown.Value);
            filename = [app.path_figure '/Figures/Plan_' num2str(app.AnalysisParameters.IK.Active)...
                '_' num2str(app.AnalysisParameters.ID.Active)...
                '_' num2str(app.AnalysisParameters.Muscles.Active)...
                '_' num2str(app.AnalysisParameters.CalibIK.Active)...
                '_' num2str(app.AnalysisParameters.CalibID.Active)...
                '_' num2str(app.AnalysisParameters.CalibMuscles.Active)...
                '_' app.DropDown.Value '.png'];
            y = imread(filename);
            I=imshow(y, 'Parent', app.UIAxes,...            
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            if app.AnalysisParameters.IK.Active
                app.OptionsButton1.BackgroundColor = [0.7 0.7 0.7];
            else
                app.OptionsButton1.BackgroundColor = [230 230 230]/255;
            end
            if app.AnalysisParameters.ID.Active
                app.OptionsButton2.BackgroundColor = [0.7 0.7 0.7];
            else
                app.OptionsButton2.BackgroundColor = [230 230 230]/255;
            end 
            if app.AnalysisParameters.Muscles.Active
                app.OptionsButton3.BackgroundColor = [0.7 0.7 0.7];
            else
                app.OptionsButton3.BackgroundColor = [230 230 230]/255;
            end 
            if app.AnalysisParameters.CalibIK.Active
                app.OptionsButton4.BackgroundColor = [1 0.8 0];
            else
                app.OptionsButton4.BackgroundColor = [255 238 170]/255;
            end
            if app.AnalysisParameters.CalibID.Active
                app.OptionsButton5.BackgroundColor = [1 0.8 0];
            else
                app.OptionsButton5.BackgroundColor = [255 238 170]/255;
            end
            if app.AnalysisParameters.CalibMuscles.Active
                app.OptionsButton6.BackgroundColor = [1 0.8 0];
            else
                app.OptionsButton6.BackgroundColor = [255 238 170]/255;
            end
            % 
            if app.AnalysisParameters.ID.InputData == 2
                app.OptionsButton7.Visible = 'on';
            else
                app.OptionsButton7.Visible = 'off';
            end
            if app.AnalysisParameters.ID.InputData == 1
                app.OptionsButton8.Visible = 'on';
            else
                app.OptionsButton8.Visible = 'off';
            end
            if app.AnalysisParameters.CalibIK.Active
                app.ListBox_2.Visible = 'on';               
            else
                app.ListBox_2.Visible = 'off';
            end
            if app.AnalysisParameters.CalibID.Active
                app.ListBox_3.Visible = 'on';
            else
                app.ListBox_3.Visible = 'off';
            end
        end
        
        function CloseApp(app)
            delete(app.OptionsIKApp)
            delete(app.OptionsCalibIKApp)
            delete(app.OptionsIDApp)
            delete(app.OptionsCalibIDApp)
            delete(app.OptionsMusclesApp)
            delete(app.OptionsCalibMusclesApp)
            delete(app.OptionsPredictionApp)
            delete(app.OptionsGeneralApp)
            delete(app)
        end
        
        function ActuateApp(app,OldAnalysisParameters)
                % Calib IK
                % k
                for i = 1:size(OldAnalysisParameters.CalibIK.LengthDelete,1)
                    [~,~,num_solid] = intersect(OldAnalysisParameters.CalibIK.LengthDelete{i,1},{app.Human_model.name});
                    app.Human_model(num_solid).calib_k_constraint = [];
                end
                for i = 1:size(OldAnalysisParameters.CalibIK.LengthAdd,1)
                    [~,~,num_solid1] = intersect(OldAnalysisParameters.CalibIK.LengthAdd{i,1},{app.Human_model.name});
                    [~,~,num_solid2] = intersect(OldAnalysisParameters.CalibIK.LengthAdd{i,2},{app.Human_model.name});
                    app.Human_model(num_solid1).calib_k_constraint = num_solid2;
                end
                % v
                for i=1:size(OldAnalysisParameters.CalibIK.AxisDelete,1)
                    [~,~,num_solid] = intersect(OldAnalysisParameters.CalibIK.AxisDelete{i,1},{app.Human_model.name});
                    app.Human_model(num_solid).v = setdiff(app.Human_model(num_solid).v',OldAnalysisParameters.CalibIK.AxisDelete{i,2}','rows')';
                end
                for i=1:size(OldAnalysisParameters.CalibIK.AxisAdd,1)
                    [~,~,num_solid] = intersect(OldAnalysisParameters.CalibIK.AxisAdd{i,1},{app.Human_model.name});
                    app.Human_model(num_solid).v = [app.Human_model(num_solid).v OldAnalysisParameters.CalibIK.AxisAdd{i,2}]; 
                end
                % Markers
                for i=1:size(OldAnalysisParameters.CalibIK.MarkersCalibModif,1)
                    [~,~,num_solid] = intersect(OldAnalysisParameters.CalibIK.MarkersCalibModif{i,1},{app.Markers_set.name});
                    app.Markers_set(num_solid).calib_dir = OldAnalysisParameters.CalibIK.MarkersCalibModif{i,2};
                end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MotionanalysisUIFigure.Resize = 'off';
            % Visualisation du modèle généré
            app.UIAxes2.Visible = 'off';
            hold(app.UIAxes2,'on');
            axis(app.UIAxes2,'equal');
            xlim(app.UIAxes2,[-0.55 0.55]);
            ylim(app.UIAxes2,[-1.2 0.9]);
            zlim(app.UIAxes2,[-0.55 0.55]);
            load('ModelParameters.mat');
            app.ModelParameters = ModelParameters;            
            app.AnimateParameters.Mode = 'GenerateParameters';
            app.AnimateParameters.seg_anim = 1;
            app.AnimateParameters.muscles_anim = 1;
            app.AnimateParameters.mod_marker_anim = 1;
            app.AnimateParameters.PictureFrame = 1;
            app.AnimateParameters.ax = app.UIAxes2;

            [app.Human_model,app.Markers_set] = ...
            PlotAnimation(app.ModelParameters, app.AnimateParameters);
%             Anim.ModelChoice = 1; Anim.CoefLineWidth = 1/3;
%             evalc('[app.Human_model,app.Markers_set] = PlotModel(app.ModelParameters,app.UIAxes2,1,1,1,0,0,0,0,0,Anim)');
            app.Human_model0 = app.Human_model; app.Markers_set0 = app.Markers_set; % Sauvegarde des modèles de base
            app.DropDown.ItemsData = {'0' '1' '2'};
            % path pour le tracé des figures
            app.path_figure = fileparts(which('Analysis'));
            % Initialisation des paramètres
                % General
                    app.AnalysisParameters.General.FilterActive = true;
                    app.AnalysisParameters.General.FilterCutOff = 10;
                    app.AnalysisParameters.General.InputData = @C3dProcessedData;
                    app.AnalysisParameters.General.InputDataOptions = {};
                    app.AnalysisParameters.General.Extension = '*.c3d';
                    app.AnalysisParameters.filename = app.ListBox.Value;
                    app.AnalysisParameters.CalibIK.filename = app.ListBox_2.Value;
                    app.AnalysisParameters.CalibID.filename = app.ListBox_3.Value;
                % CalibIK
                    % Active
                    app.AnalysisParameters.CalibIK.Active = true;
                    % Frames
                    app.AnalysisParameters.CalibIK.Frames.Method = @UniformlyDistributed;
                    app.AnalysisParameters.CalibIK.Frames.NbFrames = 30;
                % IK
                    % Active
                    app.AnalysisParameters.IK.Active = true;
                    % Method
                    app.AnalysisParameters.IK.Method = 2;
                    % Filtre
                    app.AnalysisParameters.IK.FilterActive = true;
                    app.AnalysisParameters.IK.FilterCutOff = 5;
                % ID
                    % Active
                    app.AnalysisParameters.ID.Active = true;
                    app.AnalysisParameters.ID.InputData = 2;
                % CalibID
                    % Active
                    app.AnalysisParameters.CalibID.Active = false;
                    app.AnalysisParameters.CalibID.Method = 'Stadium solid';
                    app.AnalysisParameters.CalibID.Symmetry = [];
                    app.AnalysisParameters.CalibID.Frames.NbFrames = 60;
                % Muscles
                    if numel(app.ModelParameters.Muscles) % if there are muscles
                        app.AnalysisParameters.Muscles.Active = true;
                    else
                        app.AnalysisParameters.Muscles.Active = false;
                        app.OptionsButton3.Enable = 'off';
                    end
                    app.AnalysisParameters.Muscles.Method = 1;
                    app.AnalysisParameters.Muscles.Costfunction = @PolynomialFunction;
                    app.AnalysisParameters.Muscles.CostfunctionOptions = 3;
                    app.AnalysisParameters.Muscles.DatabaseDensity = [4 4];
                    app.AnalysisParameters.Muscles.MuscleModel = @SimpleMuscleModel;
                % CalibMuscles
                    app.AnalysisParameters.CalibMuscles.Active = false;
                % External forces
                    app.AnalysisParameters.ExternalForces.FilterActive = true;
                    app.AnalysisParameters.ExternalForces.FilterCutOff = 5;
                    app.AnalysisParameters.ExternalForces.Method = @DataInC3D;
                    app.AnalysisParameters.ExternalForces.Options = {'NoContact'};
                % Prediction
                    app.AnalysisParameters.Prediction.FilterActive = true;
                    app.AnalysisParameters.Prediction.FilterCutOff = 5;
                    app.AnalysisParameters.Prediction.PositionThreshold = 0.05;
                    app.AnalysisParameters.Prediction.VelocityThreshold = 0.8;
                    app.AnalysisParameters.Prediction.FrictionCoef = 0.5;
                    app.AnalysisParameters.Prediction.ContactPoint = {};       
            % Figure
            app.UpdatePlot;
        end

        % Button pushed function: OptionsButton1
        function OptionsButton1Pushed(app, event)
            app.OptionsButton1.Enable = 'off';
            pause(0.1);
            app.OptionsCalibIKApp = OptionsIK(app,app.Human_model,app.Markers_set,app.AnalysisParameters);
        end

        % Close request function: MotionanalysisUIFigure
        function MotionanalysisUIFigureCloseRequest(app, event)
            app.CloseApp;
        end

        % Button pushed function: OptionsButton4
        function OptionsButton4Pushed(app, event)
            app.OptionsButton4.Enable = 'off';
            pause(0.1);
            app.OptionsCalibIKApp = OptionsCalibIK(app,app.Human_model,app.Markers_set,app.AnalysisParameters);
        end

        % Button pushed function: OptionsButton2
        function OptionsButton2Pushed(app, event)
            app.OptionsButton2.Enable = 'off';
            pause(0.1);
            app.OptionsIDApp = OptionsID(app,app.Human_model,app.Markers_set,app.AnalysisParameters);
        end

        % Button pushed function: OptionsButton5
        function OptionsButton5Pushed(app, event)
            app.OptionsButton5.Enable = 'off';
            pause(0.1);
            app.OptionsCalibIDApp = OptionsCalibID(app,app.Human_model,app.Markers_set,app.AnalysisParameters);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.AnalysisParameters.ID.InputData = str2double(app.DropDown.Value);
            app.UpdatePlot;
        end

        % Button pushed function: OptionsButton3
        function OptionsButton3Pushed(app, event)
            app.OptionsButton3.Enable = 'off';
            pause(0.1);
            app.OptionsMusclesApp = OptionsMuscles(app,app.Human_model,app.Markers_set,app.AnalysisParameters);
        end

        % Button pushed function: OptionsButton6
        function OptionsButton6Pushed(app, event)
            app.OptionsButton6.Enable = 'off';
            pause(0.1);
            app.OptionsCalibMusclesApp = OptionsCalibMuscles(app,app.Human_model,app.Markers_set,app.AnalysisParameters);
        end

        % Button pushed function: OptionsButton7
        function OptionsButton7Pushed(app, event)
            app.OptionsButton7.Enable = 'off';
            pause(0.1);
            app.OptionsPredictionApp = OptionsPrediction(app,app.Human_model,app.Markers_set,app.AnalysisParameters);
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            app.ParametersButton.Enable = 'off';
            pause(0.1);
            app.OptionsGeneralApp = OptionsGeneral(app,app.Human_model,app.Markers_set,app.AnalysisParameters);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = 'off';
            pause(0.1);
            % on sauvegarde tous les paramètres
            app.AnalysisParameters.filename = app.ListBox.Value;
            app.AnalysisParameters.CalibIK.filename = app.ListBox_2.Value;
            app.AnalysisParameters.CalibID.filename = app.ListBox_3.Value;
            app.AnalysisParameters.CalibIK.LengthAdd = {};
            app.AnalysisParameters.CalibIK.LengthDelete = {};
            % Calib IK k
            for i=1:numel(app.Human_model0)
               if numel(app.Human_model0(i).calib_k_constraint) || numel(app.Human_model(i).calib_k_constraint)
                   if ~numel(app.Human_model0(i).calib_k_constraint)
                       app.AnalysisParameters.CalibIK.LengthAdd = [app.AnalysisParameters.CalibIK.LengthAdd; ...
                           {app.Human_model(i).name app.Human_model(app.Human_model(i).calib_k_constraint).name}];
                   elseif ~numel(app.Human_model(i).calib_k_constraint)
                       app.AnalysisParameters.CalibIK.LengthDelete = [app.AnalysisParameters.CalibIK.LengthDelete; ...
                           {app.Human_model0(i).name app.Human_model0(app.Human_model0(i).calib_k_constraint).name}];
                   elseif app.Human_model0(i).calib_k_constraint ~= app.Human_model(i).calib_k_constraint
                       app.AnalysisParameters.CalibIK.LengthAdd = [app.AnalysisParameters.CalibIK.LengthAdd; ...
                           {app.Human_model(i).name app.Human_model(app.Human_model(i).calib_k_constraint).name}];
                       app.AnalysisParameters.CalibIK.LengthDelete = [app.AnalysisParameters.CalibIK.LengthDelete; ...
                           {app.Human_model0(i).name app.Human_model0(app.Human_model0(i).calib_k_constraint).name}];                       
                   end                   
               end
            end
            % CalibIK v
            II = eye(3);
            app.AnalysisParameters.CalibIK.AxisAdd = {};
            app.AnalysisParameters.CalibIK.AxisDelete = {};
            for i=1:numel(app.Human_model0)
                if numel(app.Human_model0(i).v) || numel(app.Human_model(i).v)
                    if ~numel(app.Human_model0(i).v)
                        app.AnalysisParameters.CalibIK.AxisAdd = [app.AnalysisParameters.CalibIK.AxisAdd; ...
                            {app.Human_model(i).name app.Human_model(i).v}];
                    elseif ~numel(app.Human_model(i).v)
                        app.AnalysisParameters.CalibIK.AxisDelete = [app.AnalysisParameters.CalibIK.AxisDelete; ...
                            {app.Human_model(i).name app.Human_model0(i).v}];
                    elseif ~isequal(app.Human_model0(i).v,app.Human_model(i).v)
                        % X
                        for j=1:3 % X, Y, Z
                            if any(app.Human_model0(i).v(j,:)) ~= any(app.Human_model(i).v(j,:))
                                if any(app.Human_model0(i).v(j,:)) % Delete
                                    app.AnalysisParameters.CalibIK.AxisDelete = [app.AnalysisParameters.CalibIK.AxisDelete; ...
                                        {app.Human_model(i).name II(:,j)}];
                                else % Add
                                    app.AnalysisParameters.CalibIK.AxisAdd = [app.AnalysisParameters.CalibIK.AxisDelete; ...
                                        {app.Human_model(i).name II(:,j)}];
                                end   
                            end
                        end
                    end
                end
            end
            % Calib IK Markers_set
            app.AnalysisParameters.CalibIK.MarkersCalibModif = {};
            for i=1:numel(app.Markers_set0)
                if ~isequal(app.Markers_set0(i).calib_dir,app.Markers_set(i).calib_dir)
                    app.AnalysisParameters.CalibIK.MarkersCalibModif =[app.AnalysisParameters.CalibIK.MarkersCalibModif;
                        {app.Markers_set(i).name app.Markers_set(i).calib_dir}];
                end
            end
            % Sauvegarde
            AnalysisParameters = app.AnalysisParameters;
            save('AnalysisParameters','AnalysisParameters');
            app.CloseApp;
            Main;
        end

        % Button pushed function: LoadparametersButton
        function LoadparametersButtonPushed(app, event)
            [FileNameParameters,PathNameParameters] = uigetfile('AnalysisParameters.mat','Select a Parameters file');
            OldAnalysisParameters = load([PathNameParameters FileNameParameters]);
            OldAnalysisParameters = OldAnalysisParameters.AnalysisParameters;
            motionsInput = intersect(OldAnalysisParameters.filename,app.ListBox.Items);
            if numel(motionsInput)
                app.ListBox.Value = motionsInput;
            end
            motionsCalibIK = intersect(OldAnalysisParameters.CalibIK.filename,app.ListBox_2.Items);
            if numel(motionsCalibIK)
               app.ListBox_2.Value = motionsCalibIK; 
            end
            motionsCalibID = intersect(OldAnalysisParameters.CalibID.filename,app.ListBox_3.Items);
            if numel(motionsCalibID)
                app.ListBox_3.Value = motionsCalibID;
            end            
            app.DropDown.Value = num2str(OldAnalysisParameters.ID.InputData);
            % Actualisation app
            app.ActuateApp(OldAnalysisParameters);
            % Sauvegarde
            app.AnalysisParameters = OldAnalysisParameters;
            app.UpdatePlot();
        end

        % Button pushed function: OptionsButton8
        function OptionsButton8Pushed(app, event)
            app.OptionsButton8.Enable = 'off';
            pause(0.1);
            app.OptionsExternalForcesApp = OptionsExternalForces(app,app.Human_model,app.Markers_set,app.AnalysisParameters);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.AnalysisParameters.filename = app.ListBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionanalysisUIFigure = uifigure('Visible', 'off');
            app.MotionanalysisUIFigure.Name = 'Motion analysis';
            app.MotionanalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @MotionanalysisUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.MotionanalysisUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.Label = uilabel(app.MotionanalysisUIFigure);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.MotionanalysisUIFigure);
            app.ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            app.ListBox_2Label = uilabel(app.MotionanalysisUIFigure);
            app.ListBox_2Label.Text = '';
            app.ListBox_2 = uilistbox(app.MotionanalysisUIFigure);
            app.ListBox_2.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8'};
            app.ParametersButton = uibutton(app.MotionanalysisUIFigure, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Text = 'Parameters';
            app.RunButton = uibutton(app.MotionanalysisUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OptionsButton1 = uibutton(app.MotionanalysisUIFigure, 'push');
            app.OptionsButton1.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton1Pushed, true);
            app.OptionsButton1.Text = 'Options';
            app.UIAxes2 = uiaxes(app.MotionanalysisUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.OptionsButton3 = uibutton(app.MotionanalysisUIFigure, 'push');
            app.OptionsButton3.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton3Pushed, true);
            app.OptionsButton3.Text = 'Options';
            app.OptionsButton2 = uibutton(app.MotionanalysisUIFigure, 'push');
            app.OptionsButton2.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton2Pushed, true);
            app.OptionsButton2.Text = 'Options';
            app.OptionsButton6 = uibutton(app.MotionanalysisUIFigure, 'push');
            app.OptionsButton6.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton6Pushed, true);
            app.OptionsButton6.Text = 'Options';
            app.OptionsButton5 = uibutton(app.MotionanalysisUIFigure, 'push');
            app.OptionsButton5.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton5Pushed, true);
            app.OptionsButton5.Text = 'Options';
            app.OptionsButton4 = uibutton(app.MotionanalysisUIFigure, 'push');
            app.OptionsButton4.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton4Pushed, true);
            app.OptionsButton4.Text = 'Options';
            app.OptionsButton7 = uibutton(app.MotionanalysisUIFigure, 'push');
            app.OptionsButton7.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton7Pushed, true);
            app.OptionsButton7.Text = 'Options';
            app.DropDown = uidropdown(app.MotionanalysisUIFigure);
            app.DropDown.Items = {'No external force', 'From experiments', 'Prediction'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Prediction';
            app.LoadparametersButton = uibutton(app.MotionanalysisUIFigure, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersButtonPushed, true);
            app.LoadparametersButton.Text = 'Load parameters';
            app.OptionsButton8 = uibutton(app.MotionanalysisUIFigure, 'push');
            app.OptionsButton8.ButtonPushedFcn = createCallbackFcn(app, @OptionsButton8Pushed, true);
            app.OptionsButton8.Text = 'Options';
            app.ListBox_3Label = uilabel(app.MotionanalysisUIFigure);
            app.ListBox_3Label.Text = '';
            app.ListBox_3 = uilistbox(app.MotionanalysisUIFigure);
            app.ListBox_3.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8'};
            % Show the figure after all components are created
            app.MotionanalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionanalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionanalysisUIFigure)
        end
    end
end