classdef MomentArmsDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MomentArmsUIFigure              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        MomentamaxeDropDownLabel        matlab.ui.control.Label
        MomentamaxeDropDown             matlab.ui.control.DropDown
        FunctionofDropDown_2            matlab.ui.control.DropDown
        FunctionofDropDown_3            matlab.ui.control.DropDown
        FunctionofDropDown_4            matlab.ui.control.DropDown
        FunctionofDropDown_5            matlab.ui.control.DropDown
        PlottingfunctionofListBoxLabel  matlab.ui.control.Label
        PlottingfunctionofListBox       matlab.ui.control.ListBox
        LiteraturecomparisonListBoxLabel  matlab.ui.control.Label
        LiteraturecomparisonListBox     matlab.ui.control.ListBox
        AngleSlider_2Label              matlab.ui.control.Label
        AngleSlider_2                   matlab.ui.control.Slider
        AngleSlider_3Label              matlab.ui.control.Label
        AngleSlider_3                   matlab.ui.control.Slider
        AngleSlider_4Label              matlab.ui.control.Label
        AngleSlider_4                   matlab.ui.control.Slider
        AngleSlider_5Label              matlab.ui.control.Label
        AngleSlider_5                   matlab.ui.control.Slider
        CreatemusclepathButton          matlab.ui.control.Button
        FunctionofDropDown_6            matlab.ui.control.DropDown
        AngleSlider_6Label              matlab.ui.control.Label
        AngleSlider_6                   matlab.ui.control.Slider
        RightPanel                      matlab.ui.container.Panel
        PrincipalInterestUIAxes         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        OsteoModel % Description
        Biom
        num_muscle
        musnames
        axes
        axes_names
    end
    
    methods (Access = public)
        
        function  ComputeMomentArms(app)
            % Compute moment arm
            eval(['app.PrincipalInterestUIAxes.Visible = ''on'';'])
            
            hold( app.PrincipalInterestUIAxes, 'off' );
            % Construction of q vector
            nb_points=15;
            Nb_q=numel(app.OsteoModel)-6*(~isempty(intersect({app.OsteoModel.name},'root0')));
            %k=2;
            axisordername = [app.PlottingfunctionofListBox.Value];
            %             for i=1:length(app.axes)-1
            %                 eval(['value = app.FunctionofDropDown_' num2str(k) '.Value;']);
            %                 axisorder  = [axisorder,app.OsteoModel(value).name];
            %                 k = k+1;
            %             end
            axisorder=[];
            for ind=1:length(axisordername)
                [~,indintersect] = intersect(app.axes_names,axisordername(ind));
                axisorder = [axisorder, app.axes(indintersect)];
            end
            
            q=zeros(Nb_q,nb_points^length(axisorder));
            
            for k=1:length(axisorder)
                joint_numk = axisorder(k);
                rangeq(:,k)=linspace(app.OsteoModel(joint_numk).limit_inf,app.OsteoModel(joint_numk).limit_sup,nb_points)';
                B1=repmat(rangeq(:,k),1,nb_points^(k-1));
                B1=B1';
                B1=B1(:)';
                B2=repmat(B1,1,nb_points^(length(axisorder)-k));
                q(joint_numk,:) = B2;
            end
            
            for j=1:4
                eval(['visible = app.FunctionofDropDown_' num2str(j+1) '.Visible;']);
                if strcmp(visible,'on')
                  eval(['joint_numj = app.FunctionofDropDown_' num2str(j+1) '.Value;']);
                  eval(['angleslider = app.AngleSlider_' num2str(j+1) '.Value;'])
                  q(joint_numj,:) = angleslider;
                end
            end
            
            mactemp=[];
            for i=1:size(q,2)
                mactemp  = [mactemp MomentArmsComputationNumMuscleJoint(app.Biom,q(:,i),0.0001,app.num_muscle,app.MomentamaxeDropDown.Value)];
            end
            
            [~,ind] = intersect(app.axes, app.MomentamaxeDropDown.Value);
            axisname = app.axes_names{ind};
            
            if length(axisorder)==2
                Zmac=zeros(nb_points);
                for k=1:length(rangeq(:,2))
                    Zmac(k,:) = mactemp((k-1)*length(rangeq(:,1))+1:k*length(rangeq(:,1)));
                end
                s = surf(app.PrincipalInterestUIAxes,rangeq(:,1)*180/pi,rangeq(:,2)*180/pi,Zmac,'FaceAlpha','0.5');
                
                
                xlabel(app.PrincipalInterestUIAxes,[axisordername{1},' (deg)'])
                ylabel(app.PrincipalInterestUIAxes,[axisordername{2},' (deg)'])
                zlabel(app.PrincipalInterestUIAxes,'Moment arm (m)')
                texttitle = [app.musnames(app.num_muscle) ' Moment arm along the ' axisname  ' axis'];
                title(app.PrincipalInterestUIAxes,[texttitle{:}])
            else
                plot(app.PrincipalInterestUIAxes,rangeq*180/pi,mactemp)
                view(app.PrincipalInterestUIAxes,2);
                xlabel(app.PrincipalInterestUIAxes,[axisordername{:},' (deg)'])
                ylabel(app.PrincipalInterestUIAxes,'Moment arm (m)')
                texttitle = [app.musnames(app.num_muscle) ' Moment arm along the ' axisname  ' axis'];
                title(app.PrincipalInterestUIAxes,[texttitle{:}])
            end
            
            if ~isempty(app.LiteraturecomparisonListBox.Value)
                LiteraturecomparisonListBoxValueChanged(app, []);
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, name_mus)
            for k=2:6
                eval(['app.FunctionofDropDown_' num2str(k) '.Visible=''off'';']);
                eval(['app.AngleSlider_' num2str(k) '.Visible=''off'';']);
                eval(['app.AngleSlider_' num2str(k) 'Label.Visible=''off'';']);
            end
            
            
            load('BiomechanicalModel.mat');
            
            % Find Muscle number
            HumanModel=BiomechanicalModel.OsteoArticularModel;
            Muscles=BiomechanicalModel.Muscles;
            
            app.OsteoModel = HumanModel;
            app.Biom = BiomechanicalModel;
            
            app.musnames={Muscles.name};
            
            [~,app.num_muscle]=intersect(app.musnames,name_mus);
            
            % Find Origin and Insertion solids
            Solid_path = Muscles(app.num_muscle).num_solid;
            
            % Find all axis on muscle path
            [sp1,sp2]=find_solid_path(HumanModel,Solid_path(1),Solid_path(end));
            app.axes = unique([sp1,sp2]);
            app.axes = app.axes(2:end);
            if ~isempty(HumanModel(app.axes(1)).FunctionalAngle)
                app.axes_names= {HumanModel(app.axes(1)).FunctionalAngle};
            else
                app.axes_names=  {HumanModel(app.axes(1)).name};
            end
            
            for k=2:length(app.axes)
                if ~isempty(HumanModel(app.axes(k)).FunctionalAngle)
                    app.axes_names= [app.axes_names , HumanModel(app.axes(k)).FunctionalAngle];
                else
                    app.axes_names= [app.axes_names , HumanModel(app.axes(k)).name];
                end
            end
            
            % Dropdown moment arm of all axis, one choice only
            eval(['app.MomentamaxeDropDown.Items = app.axes_names;'])
            eval(['app.MomentamaxeDropDown.ItemsData = app.axes;'])
            
            % Dropdown all axis, two choices max
            eval(['app.PlottingfunctionofListBox.Items=app.axes_names;']);
            eval(['app.PlottingfunctionofListBox.Value={};']);
            
            
            % Une fois qu'au moins un axe est choisi, on affiche les
            % dropdown restants
            
            
            % DropDownLitterature en lisant des fonctions dans un dossier
            path_litt = fileparts(which('RankinNeptune'));
            list_files = dir(path_litt);
            list_files = list_files(4:end);
            list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.LiteraturecomparisonListBox.Items = list_files;
            app.LiteraturecomparisonListBox.ItemsData = list_files;
            
           % MusclePathCreation(app.num_muscle);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MomentArmsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {559, 559};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {386, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function MonthlyPaymentButtonPushed(app, event)
            
            
        end

        % Value changed function: PlottingfunctionofListBox
        function PlottingfunctionofListBoxValueChanged(app, event)
            value = app.PlottingfunctionofListBox.Value;
            
            if length(value)>2
                eval(['app.PlottingfunctionofListBox.Value=value(1:2);']);
                value = app.PlottingfunctionofListBox.Value;
            end
            
            for k=2:6
                eval(['app.FunctionofDropDown_' num2str(k) '.Visible=''off'';']);
                eval(['app.AngleSlider_' num2str(k) '.Visible = ''off'';']);
                eval(['app.AngleSlider_' num2str(k) 'Label.Visible = ''off'';']);
            end


            k=2;
            otheraxes = setdiff(app.axes_names,value);
            [~,num_axes]=intersect(app.axes_names,otheraxes);
            for i=1: min(length(num_axes),5)
                eval(['app.FunctionofDropDown_' num2str(k) '.Visible=''on'';']);
                eval(['app.FunctionofDropDown_' num2str(k) '.Items = otheraxes(i:length(otheraxes));'])
                eval(['app.FunctionofDropDown_' num2str(k) '.ItemsData = app.axes(num_axes(i:length(otheraxes)));'])
                
                uppbound=app.OsteoModel(app.axes(i)).limit_sup*180/pi;
                lowbound=app.OsteoModel(app.axes(i)).limit_inf*180/pi;
                
                if isinf(uppbound)
                    uppbound=1;
                end
                if isinf(lowbound)
                    lowbound=-1;
                end
                
                eval(['app.AngleSlider_' num2str(k) '.Visible = ''on'';']);
                eval(['app.AngleSlider_' num2str(k) 'Label.Visible = ''on'';']);
                eval(['app.AngleSlider_' num2str(k) '.Limits=[lowbound uppbound];']);
                
                k = k+1;
            end
            
            
            ComputeMomentArms(app);
        end

        % Value changed function: FunctionofDropDown_2
        function FunctionofDropDown_2ValueChanged(app, event)
            value(2) = app.FunctionofDropDown_2.Value;
            Name_value = app.PlottingfunctionofListBox.Value;
            
            names = [Name_value];
            for k=2
                if ~isempty(app.OsteoModel(value(k)).FunctionalAngle)
                    names = [names,app.OsteoModel(value(k)).FunctionalAngle];
                else
                    names = [names,app.OsteoModel(value(k)).name];
                end
            end
            
            
            k=3;
            otheraxes = setdiff(app.axes_names,names);
            [~,num_axes]=intersect(app.axes_names,otheraxes);
            for i=1: min(length(num_axes),5-k)
                eval(['app.FunctionofDropDown_' num2str(k) '.Visible=''on'';']);
                eval(['app.FunctionofDropDown_' num2str(k) '.Items = otheraxes(i:length(otheraxes));'])
                eval(['app.FunctionofDropDown_' num2str(k) '.ItemsData = app.axes(num_axes(i:length(otheraxes)));'])
                k = k+1;
            end
            
            uppbound=app.OsteoModel(value2).limit_sup*180/pi;
            lowbound=app.OsteoModel(value2).limit_inf*180/pi;
            
            if isinf(uppbound)
                    uppbound=1;
                end
                if isinf(lowbound)
                    lowbound=-1;
                end
            
            eval(['app.AngleSlider_2.Limits=[lowbound uppbound];']);
            ComputeMomentArms(app);
            
        end

        % Value changed function: FunctionofDropDown_3
        function FunctionofDropDown_3ValueChanged(app, event)
            value(3) = app.FunctionofDropDown_3.Value;
            value(2) = app.FunctionofDropDown_2.Value;
            Name_value = app.PlottingfunctionofListBox.Value;
            
            names = [Name_value];
            for k=2:3
                if ~isempty(app.OsteoModel(value(k)).FunctionalAngle)
                    names = [names,app.OsteoModel(value(k)).FunctionalAngle];
                else
                    names = [names,app.OsteoModel(value(k)).name];
                end
            end
            
            k=4;
            otheraxes = setdiff(app.axes_names,names);
            [~,num_axes]=intersect(app.axes_names,otheraxes);
            for i=1:min(length(num_axes),5-k)
                eval(['app.FunctionofDropDown_' num2str(k) '.Visible=''on'';']);
                eval(['app.FunctionofDropDown_' num2str(k) '.Items = otheraxes(i:length(otheraxes));'])
                eval(['app.FunctionofDropDown_' num2str(k) '.ItemsData = app.axes(num_axes(i:length(otheraxes)));'])
                k = k+1;
            end


            uppbound=app.OsteoModel(value(3)).limit_sup*180/pi;
            lowbound=app.OsteoModel(value(3)).limit_inf*180/pi;
            if isinf(uppbound)
                    uppbound=1;
                end
                if isinf(lowbound)
                    lowbound=-1;
                end
            eval(['app.AngleSlider_3.Limits=[lowbound uppbound];']);
            ComputeMomentArms(app);
            
        end

        % Value changed function: FunctionofDropDown_4
        function FunctionofDropDown_4ValueChanged(app, event)
            value(4) = app.FunctionofDropDown_4.Value;
            value(3) = app.FunctionofDropDown_3.Value;
            value(2) = app.FunctionofDropDown_2.Value;
            Name_value = app.PlottingfunctionofListBox.Value;
            
            names = [Name_value];
            for k=2:4
                if ~isempty(app.OsteoModel(value(k)).FunctionalAngle)
                    names = [names,app.OsteoModel(value(k)).FunctionalAngle];
                else
                    names = [names,app.OsteoModel(value(k)).name];
                end
            end
            k=5;
            otheraxes = setdiff(app.axes_names,names);
            [~,num_axes]=intersect(app.axes_names,otheraxes);
            for i=1: min(length(num_axes),5-k)
                eval(['app.FunctionofDropDown_' num2str(k) '.Visible=''on'';']);
                eval(['app.FunctionofDropDown_' num2str(k) '.Items = otheraxes(i:length(otheraxes));'])
                eval(['app.FunctionofDropDown_' num2str(k) '.ItemsData = app.axes(num_axes(i:length(otheraxes)));'])
                k = k+1;
            end
            
            uppbound=app.OsteoModel(value(4)).limit_sup*180/pi;
            lowbound=app.OsteoModel(value(4)).limit_inf*180/pi;
            if isinf(uppbound)
                    uppbound=1;
                end
                if isinf(lowbound)
                    lowbound=-1;
                end
            eval(['app.AngleSlider_4.Limits=[lowbound uppbound];']);
            ComputeMomentArms(app);
            
        end

        % Value changed function: MomentamaxeDropDown
        function MomentamaxeDropDownValueChanged(app, event)
            value = app.MomentamaxeDropDown.Value;
            
        end

        % Value changed function: FunctionofDropDown_5
        function FunctionofDropDown_5ValueChanged(app, event)
            value5 = app.FunctionofDropDown_5.Value;
            
            uppbound=app.OsteoModel(value5).limit_sup*180/pi;
            lowbound=app.OsteoModel(value5).limit_inf*180/pi;
            if isinf(uppbound)
                    uppbound=1;
                end
                if isinf(lowbound)
                    lowbound=-1;
                end
            eval(['app.AngleSlider_5.Limits=[lowbound uppbound];']);
        end

        % Value changed function: AngleSlider_2
        function AngleSlider_2ValueChanged(app, event)
            ComputeMomentArms(app);
            
        end

        % Value changed function: AngleSlider_3
        function AngleSlider_3ValueChanged(app, event)
            ComputeMomentArms(app);
            
        end

        % Value changed function: AngleSlider_4
        function AngleSlider_4ValueChanged(app, event)
            ComputeMomentArms(app);
            
            
        end

        % Value changed function: AngleSlider_5
        function AngleSlider_5ValueChanged(app, event)
            ComputeMomentArms(app);
            
            
        end

        % Value changing function: AngleSlider_5
        function AngleSlider_5ValueChanging(app, event)
            ComputeMomentArms(app);
            
        end

        % Value changing function: AngleSlider_2
        function AngleSlider_2ValueChanging(app, event)
            ComputeMomentArms(app);
            
            
        end

        % Value changing function: AngleSlider_3
        function AngleSlider_3ValueChanging(app, event)
            ComputeMomentArms(app);
            
            
        end

        % Value changing function: AngleSlider_4
        function AngleSlider_4ValueChanging(app, event)
            ComputeMomentArms(app);
            
        end

        % Value changed function: LiteraturecomparisonListBox
        function LiteraturecomparisonListBoxValueChanged(app, event)
            nb_points=20;
            k=2;
            axisordername = [app.PlottingfunctionofListBox.Value];
            %             for i=1:length(app.axes)-1
            %                 eval(['value = app.FunctionofDropDown_' num2str(k) '.Value;']);
            %                 axisorder  = [axisorder,app.OsteoModel(value).name];
            %                 k = k+1;
            %             end
            
            axisorder=[];
            for ind=1:length(axisordername)
                [~,indintersect] = intersect(app.axes_names,axisordername(ind));
                axisorder = [axisorder, app.axes(indintersect)];
            end          
            q=zeros(length(app.axes),nb_points^length(axisorder));
            withoutRorL = [];
            
            for k=1:length(axisorder)
                joint_numk = axisorder(k);
                rangeq(:,k)=linspace(app.OsteoModel(joint_numk).limit_inf,app.OsteoModel(joint_numk).limit_sup,nb_points)';
                B1=repmat(rangeq(:,k),1,nb_points^(k-1));
                B1=B1';
                B1=B1(:)';
                B2=repmat(B1,1,nb_points^(length(axisorder)-k));
                q(k,:) = B2;
                temp = axisordername(k);
                withoutRorL = [withoutRorL temp ];
            end


            for j=1:4
                eval(['joint_numj = app.FunctionofDropDown_' num2str(j+1) '.Value;']);
                eval(['angleslider = app.AngleSlider_' num2str(j+1) '.Value;'])
                q(k+j,:) = angleslider;
                [~,ind] = intersect(app.axes, joint_numj);
                temp = app.axes_names(ind);
                withoutRorL = [withoutRorL temp ];
            end
            
            leg = ['Model'];
            for m=1 : length(app.LiteraturecomparisonListBox.Value)
                
                name = app.LiteraturecomparisonListBox(m).Value;
                MomentArmfunction = str2func(name{:});
                
                [~,ind] = intersect(app.axes, app.MomentamaxeDropDown.Value);
                axisname = app.axes_names{ind};
                
                [MomentsArm,~]=MomentArmfunction(app.musnames(app.num_muscle),axisname,withoutRorL,q);
                if ~isempty(MomentsArm)
                    if length(axisorder)==2
                        Zmac=zeros(nb_points);
                        for k=1:length(rangeq(:,2))
                            Zmac(k,:) = MomentsArm((k-1)*length(rangeq(:,1))+1:k*length(rangeq(:,1)));
                        end
                        hold( app.PrincipalInterestUIAxes, 'on' )
                        s = surf(app.PrincipalInterestUIAxes,rangeq(:,1)*180/pi,rangeq(:,2)*180/pi,Zmac,'FaceAlpha','0.5','EdgeColor','None');
                        s.FaceColor='interp';
                        
                        xlabel(app.PrincipalInterestUIAxes,[axisordername{1},' (deg)'])
                        ylabel(app.PrincipalInterestUIAxes,[axisordername{2},' (deg)'])
                        zlabel(app.PrincipalInterestUIAxes,'Moment arm (m)')
                        texttitle = [app.musnames(app.num_muscle) ' Moment arm along the ' axisname  ' axis'];
                        title(app.PrincipalInterestUIAxes,[texttitle{:}])
                        
                    else
                        hold( app.PrincipalInterestUIAxes, 'on' )
                        plot(app.PrincipalInterestUIAxes,rangeq*180/pi,MomentsArm)
                        view(app.PrincipalInterestUIAxes,2);
                        xlabel(app.PrincipalInterestUIAxes,[axisordername{1},' (deg)'])
                        ylabel(app.PrincipalInterestUIAxes,'Moment arm (m)')
                        texttitle = [app.musnames(app.num_muscle) ' Moment arm along the ' axisname  ' axis'];
                        title(app.PrincipalInterestUIAxes,[texttitle{:}])
                    end
                    leg = [leg, app.LiteraturecomparisonListBox(m).Value ];
                end
            end
            legend(app.PrincipalInterestUIAxes,leg);
            
            
        end

        % Button pushed function: CreatemusclepathButton
        function CreatemusclepathButtonPushed(app, event)
            MusclePathCreation(app.num_muscle);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MomentArmsUIFigure = uifigure('Visible', 'off');
            app.MomentArmsUIFigure.Name = 'Moment Arms';
            app.MomentArmsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MomentArmsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MomentamaxeDropDownLabel = uilabel(app.LeftPanel);
            app.MomentamaxeDropDownLabel.Text = 'Moment am axe';
            app.MomentamaxeDropDown = uidropdown(app.LeftPanel);
            app.MomentamaxeDropDown.ValueChangedFcn = createCallbackFcn(app, @MomentamaxeDropDownValueChanged, true);
            app.FunctionofDropDown_2 = uidropdown(app.LeftPanel);
            app.FunctionofDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FunctionofDropDown_2ValueChanged, true);
            app.FunctionofDropDown_3 = uidropdown(app.LeftPanel);
            app.FunctionofDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FunctionofDropDown_3ValueChanged, true);
            app.FunctionofDropDown_4 = uidropdown(app.LeftPanel);
            app.FunctionofDropDown_4.ValueChangedFcn = createCallbackFcn(app, @FunctionofDropDown_4ValueChanged, true);
            app.FunctionofDropDown_5 = uidropdown(app.LeftPanel);
            app.FunctionofDropDown_5.ValueChangedFcn = createCallbackFcn(app, @FunctionofDropDown_5ValueChanged, true);
            app.PlottingfunctionofListBoxLabel = uilabel(app.LeftPanel);
            app.PlottingfunctionofListBoxLabel.Text = 'Plotting function of';
            app.PlottingfunctionofListBox = uilistbox(app.LeftPanel);
            app.PlottingfunctionofListBox.Multiselect = 'on';
            app.PlottingfunctionofListBox.ValueChangedFcn = createCallbackFcn(app, @PlottingfunctionofListBoxValueChanged, true);
            app.PlottingfunctionofListBox.Value = {'Item 1'};
            app.LiteraturecomparisonListBoxLabel = uilabel(app.LeftPanel);
            app.LiteraturecomparisonListBoxLabel.Text = 'Literature comparison';
            app.LiteraturecomparisonListBox = uilistbox(app.LeftPanel);
            app.LiteraturecomparisonListBox.Multiselect = 'on';
            app.LiteraturecomparisonListBox.ValueChangedFcn = createCallbackFcn(app, @LiteraturecomparisonListBoxValueChanged, true);
            app.LiteraturecomparisonListBox.Value = {};
            app.AngleSlider_2Label = uilabel(app.LeftPanel);
            app.AngleSlider_2Label.Text = 'Angle';
            app.AngleSlider_2 = uislider(app.LeftPanel);
            app.AngleSlider_2.ValueChangedFcn = createCallbackFcn(app, @AngleSlider_2ValueChanged, true);
            app.AngleSlider_2.ValueChangingFcn = createCallbackFcn(app, @AngleSlider_2ValueChanging, true);
            app.AngleSlider_3Label = uilabel(app.LeftPanel);
            app.AngleSlider_3Label.Text = 'Angle';
            app.AngleSlider_3 = uislider(app.LeftPanel);
            app.AngleSlider_3.ValueChangedFcn = createCallbackFcn(app, @AngleSlider_3ValueChanged, true);
            app.AngleSlider_3.ValueChangingFcn = createCallbackFcn(app, @AngleSlider_3ValueChanging, true);
            app.AngleSlider_4Label = uilabel(app.LeftPanel);
            app.AngleSlider_4Label.Text = 'Angle';
            app.AngleSlider_4 = uislider(app.LeftPanel);
            app.AngleSlider_4.ValueChangedFcn = createCallbackFcn(app, @AngleSlider_4ValueChanged, true);
            app.AngleSlider_4.ValueChangingFcn = createCallbackFcn(app, @AngleSlider_4ValueChanging, true);
            app.AngleSlider_5Label = uilabel(app.LeftPanel);
            app.AngleSlider_5Label.Text = 'Angle';
            app.AngleSlider_5 = uislider(app.LeftPanel);
            app.AngleSlider_5.ValueChangedFcn = createCallbackFcn(app, @AngleSlider_5ValueChanged, true);
            app.AngleSlider_5.ValueChangingFcn = createCallbackFcn(app, @AngleSlider_5ValueChanging, true);
            app.CreatemusclepathButton = uibutton(app.LeftPanel, 'push');
            app.CreatemusclepathButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemusclepathButtonPushed, true);
            app.CreatemusclepathButton.Text = 'Create muscle path';
            app.FunctionofDropDown_6 = uidropdown(app.LeftPanel);
            app.AngleSlider_6Label = uilabel(app.LeftPanel);
            app.AngleSlider_6Label.Text = 'Angle';
            app.AngleSlider_6 = uislider(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Moment arm')
            xlabel(app.PrincipalInterestUIAxes, 'Angle')
            ylabel(app.PrincipalInterestUIAxes, 'Moment arm')
            app.PrincipalInterestUIAxes.LineStyleOrder = {'-'; '- +'; '- o'; '- d'; '- s'};
            app.PrincipalInterestUIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.MomentArmsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MomentArmsDisplay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MomentArmsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MomentArmsUIFigure)
        end
    end
end