classdef OptionsMatrixGiven < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatrixGivenUIFigure          matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        OKButton                     matlab.ui.control.Button
        ContactdetectionfileListBoxLabel  matlab.ui.control.Label
        ContactdetectionfileListBox  matlab.ui.control.ListBox
    end

properties (Access = private)
        CallingApp
        AnalysisParameters
end
    methods (Access = private)
        
        function UpdatePlot(app)
            list_files_txt=dir('*.txt');
            app.ContactdetectionfileListBox.Items = {list_files_txt.name};
        end
        
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            OldAnalysisParameters.Prediction.ContactDetectionFile = app.ContactdetectionfileListBox.Value;
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.AnalysisParameters = OldAnalysisParameters;
            app.UpdatePlot;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveAnalysisParameters(app.CallingApp,app.AnalysisParameters);
            app.MatrixGivenUIFigureCloseRequest; 
        end

        % Close request function: MatrixGivenUIFigure
        function MatrixGivenUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: ContactdetectionfileListBox
        function ContactdetectionfileListBoxValueChanged(app, event)
            value = app.ContactdetectionfileListBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatrixGivenUIFigure = uifigure('Visible', 'off');
            app.MatrixGivenUIFigure.Name = 'UI Figure';
            app.MatrixGivenUIFigure.CloseRequestFcn = createCallbackFcn(app, @MatrixGivenUIFigureCloseRequest, true);
            app.Panel = uipanel(app.MatrixGivenUIFigure);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ContactdetectionfileListBoxLabel = uilabel(app.MatrixGivenUIFigure);
            app.ContactdetectionfileListBoxLabel.Text = 'Contact detection file';
            app.ContactdetectionfileListBox = uilistbox(app.MatrixGivenUIFigure);
            app.ContactdetectionfileListBox.ValueChangedFcn = createCallbackFcn(app, @ContactdetectionfileListBoxValueChanged, true);
            % Show the figure after all components are created
            app.MatrixGivenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionsMatrixGiven(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatrixGivenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatrixGivenUIFigure)
        end
    end
end