classdef OptionsMuscles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MuscleforcesestimationoptionsUIFigure  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        ActivestepCheckBox        matlab.ui.control.CheckBox
        OKButton                  matlab.ui.control.Button
        MethodDropDown            matlab.ui.control.DropDown
        MethodLabel               matlab.ui.control.Label
        CostfunctionLabel         matlab.ui.control.Label
        MethodDropDown_2          matlab.ui.control.DropDown
        DatabasedensityLabel      matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        k1Label                   matlab.ui.control.Label
        k2Label                   matlab.ui.control.Label
        OrderLabel                matlab.ui.control.Label
        EditField_3               matlab.ui.control.NumericEditField
        MuscleactivationDropDown  matlab.ui.control.DropDown
        MusclemodelLabel          matlab.ui.control.Label
        StiffnessCheckBox         matlab.ui.control.CheckBox
        AddButton                 matlab.ui.control.Button
        StiffnessValue1           matlab.ui.control.NumericEditField
        StiffnessvalueLabel       matlab.ui.control.Label
        StiffnessValue2           matlab.ui.control.NumericEditField
        StiffnessValue3           matlab.ui.control.NumericEditField
        StiffnessValue4           matlab.ui.control.NumericEditField
        StiffnessValue5           matlab.ui.control.NumericEditField
        DeleteButton              matlab.ui.control.Button
        DropDownSolid_4           matlab.ui.control.DropDown
        ListBox_4                 matlab.ui.control.ListBox
        DropDownSolid_3           matlab.ui.control.DropDown
        ListBox_3                 matlab.ui.control.ListBox
        DropDownSolid_2           matlab.ui.control.DropDown
        ListBox_2                 matlab.ui.control.ListBox
        DropDownSolid_1           matlab.ui.control.DropDown
        ListBox_1                 matlab.ui.control.ListBox
        DropDownSolid_5           matlab.ui.control.DropDown
        ListBox_5                 matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        CallingApp
        Human_model % Description
        Human_model_Pts
        Markers_set
        AnalysisParameters
        num_p
    end
    
    methods (Access = private)
        
        function ActuateApp(app,OldAnalysisParameters)
            % Active
            app.ActivestepCheckBox.Value = OldAnalysisParameters.Muscles.Active;
            % Method
            app.MethodDropDown.Value = num2str(OldAnalysisParameters.Muscles.Method);
            app.MethodDropDown_2.Value = func2str(OldAnalysisParameters.Muscles.Costfunction);
            app.MethodDropDownValueChanged;
            app.MethodDropDown_2ValueChanged;
            app.EditField_3.Value = OldAnalysisParameters.Muscles.CostfunctionOptions;
            Musclemodel = func2str(OldAnalysisParameters.Muscles.MuscleModel);
            app.MuscleactivationDropDown.Value = Musclemodel(1:end-11);
            % Density
            app.EditField.Value = OldAnalysisParameters.Muscles.DatabaseDensity(1);
            app.EditField_2.Value = OldAnalysisParameters.Muscles.DatabaseDensity(2);
            % Active
            app.ActivestepCheckBoxValueChanged;
            
            if isfield(OldAnalysisParameters.Muscles,'Stiffness') && ~isempty(OldAnalysisParameters.Muscles.Stiffness.effector)
                app.StiffnessCheckBox.Value=1;
                list_solid = OldAnalysisParameters.Muscles.Stiffness.effector(:,1);
                list_points = OldAnalysisParameters.Muscles.Stiffness.effector(:,2);
                listalpha = OldAnalysisParameters.Muscles.Stiffness.alpha;
                app.num_p = numel(list_solid);
                eval(['app.StiffnessvalueLabel.Visible = "on";'])
                eval(['app.AddButton.Visible = "on";'])
                eval(['app.DeleteButton.Visible = "on";'])
            else
                app.num_p = 0;
            end
            for i=1:app.num_p
                eval(['app.DropDownSolid_' num2str(i) '.Visible = ''on'';'])
                eval(['app.ListBox_' num2str(i) '.Visible = ''on'';'])
                eval(['app.DropDownSolid_' num2str(i) '.Value = app.Human_model_Pts(list_solid(i)).name;'])
                eval(['app.StiffnessValue' num2str(i) '.Visible = "on";'])
                eval(['app.StiffnessValue' num2str(i) '.Value = listalpha(i);'])
            end
            for i=(app.num_p+1):5
                eval(['app.DropDownSolid_' num2str(i) '.Visible = ''off'';'])
                eval(['app.ListBox_' num2str(i) '.Visible = ''off'';'])
            end
            app.ActuatePoints();
            for i=1:app.num_p
                eval(['app.ListBox_' num2str(i) '.Value =app.Human_model_Pts(list_solid(i)).anat_position{list_points(i),1};'])
            end

        end
        
        function ActuatePoints(app)
            for i=1:5
                [~,~,num_solid] = eval(['intersect(app.DropDownSolid_' num2str(i) '.Value,{app.Human_model_Pts.name});']);
                eval(['app.ListBox_' num2str(i) '.Items = {app.Human_model_Pts(num_solid).anat_position{:,1}};'])
            end
        end
        
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            % Active
            OldAnalysisParameters.Muscles.Active = app.ActivestepCheckBox.Value;
            % Method
            OldAnalysisParameters.Muscles.Method = str2double(app.MethodDropDown.Value);
            OldAnalysisParameters.Muscles.Costfunction = str2func(app.MethodDropDown_2.Value);
            OldAnalysisParameters.Muscles.CostfunctionOptions = app.EditField_3.Value;
            OldAnalysisParameters.Muscles.MuscleModel = str2func([app.MuscleactivationDropDown.Value 'MuscleModel']);
            % Density
            OldAnalysisParameters.Muscles.DatabaseDensity(1) = app.EditField.Value;
            OldAnalysisParameters.Muscles.DatabaseDensity(2) = app.EditField_2.Value;
            OldAnalysisParameters.Muscles.Stiffness.effector=[];
            OldAnalysisParameters.Muscles.Stiffness.alpha=[];
            
            for i=1:app.num_p
                [~,~,num_solid] = eval(['intersect(app.DropDownSolid_' num2str(i) '.Value,{app.Human_model_Pts.name});']);
                [~,~,num_eff] = eval(['intersect(app.ListBox_' num2str(i) '.Value,{app.Human_model_Pts(num_solid).anat_position{:,1}});']);
                OldAnalysisParameters.Muscles.Stiffness.effector(i,:) = [num_solid num_eff];
                OldAnalysisParameters.Muscles.Stiffness.alpha(i) = eval(['app.StiffnessValue' num2str(i) '.Value;']);
            end


            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            if isequal(OldAnalysisParameters.General.InputData, @MVNX_V3)
                ModelParameters.Mass = 0;
                app.Human_model_Pts = MVNXModelGeneration(ModelParameters, OldAnalysisParameters);
            else
                app.Human_model_Pts = Human_model;
            end
            
            app.Markers_set = Markers_set;
            app.MethodDropDown.ItemsData = {'1' '2'};
            
            list_solid = cellfun(@iscell, {app.Human_model_Pts.anat_position});
            for i=1:5
                eval(['app.DropDownSolid_' num2str(i) '.Items = {app.Human_model_Pts(list_solid).name};'])
                eval(['app.StiffnessValue' num2str(i) '.Visible = "off";'])
            end
            eval(['app.StiffnessvalueLabel.Visible = "off";'])
            
            eval(['app.AddButton.Visible = "off";'])
            eval(['app.DeleteButton.Visible = "off";'])
            
            % Liste des fonctions de cout
            path_models = fileparts(which('MuscleForcesComputation'));
            list_files = dir([path_models '/CostFunction/*.m']); list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.MethodDropDown_2.Items = list_files; app.MethodDropDown_2.ItemsData = list_files;
            % Actualisation des paramètres
            ActuateApp(app,OldAnalysisParameters);
            app.AnalysisParameters = OldAnalysisParameters;
        end

        % Close request function: 
        % MuscleforcesestimationoptionsUIFigure
        function MuscleforcesestimationoptionsUIFigureCloseRequest(app, event)
            EnableButton3(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.MuscleforcesestimationoptionsUIFigureCloseRequest;
        end

        % Value changed function: ActivestepCheckBox
        function ActivestepCheckBoxValueChanged(app, event)
          if app.ActivestepCheckBox.Value
               app.MethodDropDown.Visible = 'on';
               app.MethodDropDown_2.Visible = 'on';
               app.MethodLabel.Visible = 'on';
               app.CostfunctionLabel.Visible = 'on';
               app.DatabasedensityLabel.Visible = 'on';
               app.k1Label.Visible = 'on';
               app.k2Label.Visible = 'on';
               app.EditField.Visible = 'on';
               app.EditField_2.Visible = 'on';
               app.OrderLabel.Visible = 'on';
               app.EditField_3.Visible = 'on';
               app.MethodDropDownValueChanged;
               app.MethodDropDown_2ValueChanged;
               app.MuscleactivationDropDown.Visible = 'on';
               app.MusclemodelLabel.Visible = 'on';
          else
               app.MethodDropDown.Visible = 'off';
               app.MethodDropDown_2.Visible = 'off';
               app.MethodLabel.Visible = 'off';
               app.CostfunctionLabel.Visible = 'off';
               app.DatabasedensityLabel.Visible = 'off';
               app.k1Label.Visible = 'off';
               app.k2Label.Visible = 'off';
               app.EditField.Visible = 'off';
               app.EditField_2.Visible = 'off';
               app.OrderLabel.Visible = 'off';
               app.EditField_3.Visible = 'off';
               app.MuscleactivationDropDown.Visible = 'off';
               app.MusclemodelLabel.Visible = 'off';
          end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            if str2double(app.MethodDropDown.Value) == 2
                app.DatabasedensityLabel.Visible = 'on';
                app.EditField.Visible = 'on';
                app.k1Label.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.k2Label.Visible='on';
                app.MuscleactivationDropDown.Visible = 'off';
                app.MusclemodelLabel.Visible = 'off';
            else
                app.DatabasedensityLabel.Visible = 'off';
                app.EditField.Visible = 'off';
                app.k1Label.Visible = 'off';
                app.EditField_2.Visible = 'off';
                app.k2Label.Visible='off';
                app.MuscleactivationDropDown.Visible = 'on';
                app.MusclemodelLabel.Visible = 'on';
            end
        end

        % Value changed function: MethodDropDown_2
        function MethodDropDown_2ValueChanged(app, event)
            if strcmp(app.MethodDropDown_2.Value,'PolynomialFunction')
                app.OrderLabel.Visible = 'on';
                app.EditField_3.Visible = 'on';
            else
                app.OrderLabel.Visible = 'off';
                app.EditField_3.Visible = 'off';
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            eval(['app.StiffnessvalueLabel.Visible = "on";'])
            
            if app.num_p <5
                app.num_p = app.num_p + 1;
                eval(['app.DropDownSolid_' num2str(app.num_p) '.Visible = ''on'';'])
                eval(['app.ListBox_' num2str(app.num_p) '.Visible = ''on'';'])
                eval(['app.StiffnessValue' num2str(app.num_p) '.Visible = ''on'';'])
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if app.num_p
                eval(['app.DropDownSolid_' num2str(app.num_p) '.Visible = ''off'';'])
                eval(['app.ListBox_' num2str(app.num_p) '.Visible = ''off'';'])
                eval(['app.StiffnessValue' num2str(app.num_p) '.Visible = ''off'';'])
                app.num_p = app.num_p - 1;
            end
        end

        % Value changed function: StiffnessCheckBox
        function StiffnessCheckBoxValueChanged(app, event)
            if app.StiffnessCheckBox.Value
                eval(['app.AddButton.Visible = "on";'])
                eval(['app.DeleteButton.Visible = "on";'])


            else
            for i=1:5
                eval(['app.DropDownSolid_' num2str(i) '.Visible = "off";'])
                eval(['app.ListBox_' num2str(i) '.Visible = "off";'])
                eval(['app.StiffnessValue' num2str(i) '.Visible = "off";'])
            end
            eval(['app.StiffnessvalueLabel.Visible = "off";'])
            eval(['app.AddButton.Visible = "off";'])
            eval(['app.DeleteButton.Visible = "off";'])

            end
            
        end

        % Value changed function: DropDownSolid_1
        function DropDownSolid_1ValueChanged(app, event)
            app.ActuatePoints;
        end

        % Value changed function: DropDownSolid_2
        function DropDownSolid_2ValueChanged(app, event)
            app.ActuatePoints;
        end

        % Value changed function: DropDownSolid_3
        function DropDownSolid_3ValueChanged(app, event)
            app.ActuatePoints;            
        end

        % Value changed function: DropDownSolid_4
        function DropDownSolid_4ValueChanged(app, event)
            app.ActuatePoints;
        end

        % Value changed function: DropDownSolid_5
        function DropDownSolid_5ValueChanged(app, event)
            app.ActuatePoints;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MuscleforcesestimationoptionsUIFigure = uifigure('Visible', 'off');
            app.MuscleforcesestimationoptionsUIFigure.Name = 'Muscle forces estimation options';
            app.MuscleforcesestimationoptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MuscleforcesestimationoptionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.MuscleforcesestimationoptionsUIFigure);
            app.ActivestepCheckBox = uicheckbox(app.Panel);
            app.ActivestepCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivestepCheckBoxValueChanged, true);
            app.ActivestepCheckBox.Text = '   Active step';
            app.ActivestepCheckBox.Value = true;
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.MethodDropDown = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.MethodDropDown.Items = {'Optimization problem', 'MusIC method'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Optimization problem';
            app.MethodLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.MethodLabel.Text = 'Method';
            app.CostfunctionLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.CostfunctionLabel.Text = 'Cost function';
            app.MethodDropDown_2 = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.MethodDropDown_2.Items = {'1', '2', '3'};
            app.MethodDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MethodDropDown_2ValueChanged, true);
            app.MethodDropDown_2.Value = '3';
            app.DatabasedensityLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.DatabasedensityLabel.Text = 'Database density';
            app.EditField = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField_2 = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.k1Label = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.k1Label.Text = 'k1';
            app.k2Label = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.k2Label.Text = 'k2';
            app.OrderLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.OrderLabel.Text = 'Order';
            app.EditField_3 = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.MuscleactivationDropDown = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.MuscleactivationDropDown.Items = {'Simple', 'ForceLength', 'ForceLengthVelocity'};
            app.MuscleactivationDropDown.Value = 'Simple';
            app.MusclemodelLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.MusclemodelLabel.Text = 'Muscle model';
            app.StiffnessCheckBox = uicheckbox(app.MuscleforcesestimationoptionsUIFigure);
            app.StiffnessCheckBox.ValueChangedFcn = createCallbackFcn(app, @StiffnessCheckBoxValueChanged, true);
            app.StiffnessCheckBox.Text = 'Stiffness';
            app.AddButton = uibutton(app.MuscleforcesestimationoptionsUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.StiffnessValue1 = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.StiffnessValue1.Limits = [0 Inf];
            app.StiffnessvalueLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.StiffnessvalueLabel.Text = 'Stiffness value';
            app.StiffnessValue2 = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.StiffnessValue2.Limits = [0 Inf];
            app.StiffnessValue3 = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.StiffnessValue3.Limits = [0 Inf];
            app.StiffnessValue4 = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.StiffnessValue4.Limits = [0 Inf];
            app.StiffnessValue5 = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.StiffnessValue5.Limits = [0 Inf];
            app.DeleteButton = uibutton(app.MuscleforcesestimationoptionsUIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.DropDownSolid_4 = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.DropDownSolid_4.Items = {'Solid 1', 'Solid 2'};
            app.DropDownSolid_4.ValueChangedFcn = createCallbackFcn(app, @DropDownSolid_4ValueChanged, true);
            app.DropDownSolid_4.Value = 'Solid 1';
            app.ListBox_4 = uilistbox(app.MuscleforcesestimationoptionsUIFigure);
            app.ListBox_4.Items = {'Point 1', 'Point 2'};
            app.ListBox_4.Multiselect = 'on';
            app.ListBox_4.Value = {'Point 1'};
            app.DropDownSolid_3 = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.DropDownSolid_3.Items = {'Solid 1', 'Solid 2'};
            app.DropDownSolid_3.ValueChangedFcn = createCallbackFcn(app, @DropDownSolid_3ValueChanged, true);
            app.DropDownSolid_3.Value = 'Solid 1';
            app.ListBox_3 = uilistbox(app.MuscleforcesestimationoptionsUIFigure);
            app.ListBox_3.Items = {'Point 1', 'Point 2'};
            app.ListBox_3.Multiselect = 'on';
            app.ListBox_3.Value = {'Point 1'};
            app.DropDownSolid_2 = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.DropDownSolid_2.Items = {'Solid 1', 'Solid 2'};
            app.DropDownSolid_2.ValueChangedFcn = createCallbackFcn(app, @DropDownSolid_2ValueChanged, true);
            app.DropDownSolid_2.Value = 'Solid 1';
            app.ListBox_2 = uilistbox(app.MuscleforcesestimationoptionsUIFigure);
            app.ListBox_2.Items = {'Point 1', 'Point 2'};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.Value = {'Point 1'};
            app.DropDownSolid_1 = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.DropDownSolid_1.Items = {'Solid 1', 'Solid 2'};
            app.DropDownSolid_1.ValueChangedFcn = createCallbackFcn(app, @DropDownSolid_1ValueChanged, true);
            app.DropDownSolid_1.Value = 'Solid 1';
            app.ListBox_1 = uilistbox(app.MuscleforcesestimationoptionsUIFigure);
            app.ListBox_1.Items = {'Point 1', 'Point 2'};
            app.ListBox_1.Multiselect = 'on';
            app.ListBox_1.Value = {'Point 1'};
            app.DropDownSolid_5 = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.DropDownSolid_5.Items = {'Solid 1', 'Solid 2'};
            app.DropDownSolid_5.ValueChangedFcn = createCallbackFcn(app, @DropDownSolid_5ValueChanged, true);
            app.DropDownSolid_5.Value = 'Solid 1';
            app.ListBox_5 = uilistbox(app.MuscleforcesestimationoptionsUIFigure);
            app.ListBox_5.Items = {'Point 1', 'Point 2'};
            app.ListBox_5.Multiselect = 'on';
            app.ListBox_5.Value = {'Point 1'};
            % Show the figure after all components are created
            app.MuscleforcesestimationoptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionsMuscles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MuscleforcesestimationoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MuscleforcesestimationoptionsUIFigure)
        end
    end
end