classdef OptionsPrediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExternalforcespredictionoptionsUIFigure  matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        OKButton                       matlab.ui.control.Button
        CheckBox                       matlab.ui.control.CheckBox
        PredictedforcesfilteringLabel  matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        CutofffrenquencyHzLabel        matlab.ui.control.Label
        PositionthresholdmLabel        matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        VelocitythresholdmsLabel       matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        FrictioncoefficientLabel       matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        ContactPointsPanel             matlab.ui.container.Panel
        DropDown_4                     matlab.ui.control.DropDown
        ListBox_4                      matlab.ui.control.ListBox
        DropDown_3                     matlab.ui.control.DropDown
        ListBox_3                      matlab.ui.control.ListBox
        DropDown_2                     matlab.ui.control.DropDown
        ListBox_2                      matlab.ui.control.ListBox
        DropDown_1                     matlab.ui.control.DropDown
        ListBox_1                      matlab.ui.control.ListBox
        AddButton                      matlab.ui.control.Button
        DeleteButton                   matlab.ui.control.Button
        AdditionalmarkersCheckBox      matlab.ui.control.CheckBox
    end


    properties (Access = private)
        CallingApp
        Human_model % Description
        Markers_set
        AnalysisParameters
        num_p
        Human_model_Pts
        AdditionalMarkersUIFigure
        OptionsAdditionalMarkersApp
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            app.CheckBox.Value = OldAnalysisParameters.Prediction.FilterActive;
            app.CheckBoxValueChanged;
            app.EditField.Value = OldAnalysisParameters.Prediction.FilterCutOff;
            app.EditField_2.Value = OldAnalysisParameters.Prediction.PositionThreshold;
            app.EditField_3.Value = OldAnalysisParameters.Prediction.VelocityThreshold;
            app.EditField_4.Value = OldAnalysisParameters.Prediction.FrictionCoef;
            pos_pts = [];
            for i=1:numel(OldAnalysisParameters.Prediction.ContactPoint)
                for j = 1:numel(app.Human_model_Pts)
                    if numel(app.Human_model_Pts(j).anat_position)
                        [a,~,c] = intersect(OldAnalysisParameters.Prediction.ContactPoint{i},{app.Human_model_Pts(j).anat_position{:,1}});
                        if numel(a)
                            pos_pts = [pos_pts;j,c];
                            break;
                        end
                    end
                end
            end
            if numel(pos_pts)
                list_solid = unique(pos_pts(:,1));
                app.num_p = numel(list_solid);
            else
                app.num_p = 0;
            end
            for i=1:app.num_p
                eval(['app.DropDown_' num2str(i) '.Visible = ''on'';'])
                eval(['app.ListBox_' num2str(i) '.Visible = ''on'';'])
                eval(['app.DropDown_' num2str(i) '.Value = app.Human_model_Pts(list_solid(i)).name;'])
            end
            for i=(app.num_p+1):4
                eval(['app.DropDown_' num2str(i) '.Visible = ''off'';'])
                eval(['app.ListBox_' num2str(i) '.Visible = ''off'';'])
            end
            app.ActuatePoints();
            for i=1:app.num_p
                list_points = find(pos_pts(:,1)==list_solid(i));
                eval(['app.ListBox_' num2str(i) '.Value = {app.Human_model_Pts(list_solid(i)).anat_position{pos_pts(list_points,2),1}};'])
            end
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            OldAnalysisParameters.Prediction.FilterActive = app.CheckBox.Value;
            OldAnalysisParameters.Prediction.FilterCutOff = app.EditField.Value;
            OldAnalysisParameters.Prediction.PositionThreshold = app.EditField_2.Value;
            OldAnalysisParameters.Prediction.VelocityThreshold = app.EditField_3.Value;
            OldAnalysisParameters.Prediction.FrictionCoef = app.EditField_4.Value;
            OldAnalysisParameters.Prediction.ContactPoint = {};
            OldAnalysisParameters.ID.AddMarkers = app.AdditionalmarkersCheckBox.Value;
            for i=1:app.num_p
                OldAnalysisParameters.Prediction.ContactPoint = [OldAnalysisParameters.Prediction.ContactPoint ...
                    eval(['app.ListBox_' num2str(i) '.Value'])];
            end
            OldAnalysisParameters.Prediction.ContactPoint = unique(OldAnalysisParameters.Prediction.ContactPoint);
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
        function ActuatePoints(app)
            for i=1:4
                [~,~,num_solid] = eval(['intersect(app.DropDown_' num2str(i) '.Value,{app.Human_model_Pts.name});']);
                eval(['app.ListBox_' num2str(i) '.Items = {app.Human_model_Pts(num_solid).anat_position{:,1}};'])             
            end
        end

    end
    
    methods (Access = public)
        
        function SaveAnalysisParameters(app,NewAnalysisParameters)
            app.AnalysisParameters = NewAnalysisParameters;
        end
            
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            if isequal(OldAnalysisParameters.General.InputData, @MVNX_V3)
                ModelParameters.Mass = 0;
                app.Human_model_Pts = MVNXModelGeneration(ModelParameters, OldAnalysisParameters);
            else
                app.Human_model_Pts = Human_model;
            end            
            app.Markers_set = Markers_set;
            % ContactPoint
            list_solid = cellfun(@iscell, {app.Human_model_Pts.anat_position});
            for i=1:4
                eval(['app.DropDown_' num2str(i) '.Items = {app.Human_model_Pts(list_solid).name};'])
            end
            % Actualisation des paramètres
            ActuateApp(app,OldAnalysisParameters);
            app.AnalysisParameters = OldAnalysisParameters;
        end

        % Close request function: 
        % ExternalforcespredictionoptionsUIFigure
        function ExternalforcespredictionoptionsUIFigureCloseRequest(app, event)
            EnableButton7(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.ExternalforcespredictionoptionsUIFigureCloseRequest;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.CheckBox.Value
                app.CutofffrenquencyHzLabel.Visible = 'on';
                app.EditField.Visible = 'on';
            else
                app.CutofffrenquencyHzLabel.Visible = 'off';
                app.EditField.Visible = 'off';
            end
        end

        % Value changed function: DropDown_1, DropDown_2, 
        % DropDown_3, DropDown_4
        function DropDown_1ValueChanged(app, event)
            app.ActuatePoints;            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.num_p <4
                app.num_p = app.num_p + 1;
                eval(['app.DropDown_' num2str(app.num_p) '.Visible = ''on'';'])
                eval(['app.ListBox_' num2str(app.num_p) '.Visible = ''on'';'])               
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if app.num_p
                eval(['app.DropDown_' num2str(app.num_p) '.Visible = ''off'';'])
                eval(['app.ListBox_' num2str(app.num_p) '.Visible = ''off'';'])   
                app.num_p = app.num_p - 1;
            end
        end

        % Value changed function: AdditionalmarkersCheckBox
        function AdditionalmarkersCheckBoxValueChanged(app, event)
            value = app.AdditionalmarkersCheckBox.Value;
            app.AnalysisParameters.ID.AddMarkers = value;
            if value
                app.OptionsAdditionalMarkersApp = OptionsAdditionalMarkers(app,app.AnalysisParameters);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExternalforcespredictionoptionsUIFigure = uifigure('Visible', 'off');
            app.ExternalforcespredictionoptionsUIFigure.Name = 'External forces prediction options';
            app.ExternalforcespredictionoptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExternalforcespredictionoptionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.ExternalforcespredictionoptionsUIFigure);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CheckBox = uicheckbox(app.ExternalforcespredictionoptionsUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.PredictedforcesfilteringLabel = uilabel(app.ExternalforcespredictionoptionsUIFigure);
            app.PredictedforcesfilteringLabel.Text = 'Predicted forces filtering';
            app.EditField = uieditfield(app.ExternalforcespredictionoptionsUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 5;
            app.CutofffrenquencyHzLabel = uilabel(app.ExternalforcespredictionoptionsUIFigure);
            app.CutofffrenquencyHzLabel.Text = 'Cut-off frenquency (Hz)';
            app.PositionthresholdmLabel = uilabel(app.ExternalforcespredictionoptionsUIFigure);
            app.PositionthresholdmLabel.Text = 'Position threshold (m)';
            app.EditField_2 = uieditfield(app.ExternalforcespredictionoptionsUIFigure, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.Value = 0.05;
            app.VelocitythresholdmsLabel = uilabel(app.ExternalforcespredictionoptionsUIFigure);
            app.VelocitythresholdmsLabel.Text = 'Velocity threshold (m/s)';
            app.EditField_3 = uieditfield(app.ExternalforcespredictionoptionsUIFigure, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.Value = 0.8;
            app.FrictioncoefficientLabel = uilabel(app.ExternalforcespredictionoptionsUIFigure);
            app.FrictioncoefficientLabel.Text = 'Friction coefficient';
            app.EditField_4 = uieditfield(app.ExternalforcespredictionoptionsUIFigure, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.Value = 0.8;
            app.ContactPointsPanel = uipanel(app.ExternalforcespredictionoptionsUIFigure);
            app.ContactPointsPanel.Title = ' Contact Points';
            app.DropDown_4 = uidropdown(app.ContactPointsPanel);
            app.DropDown_4.Items = {'Solid 1', 'Solid 2'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_4.Value = 'Solid 1';
            app.ListBox_4 = uilistbox(app.ContactPointsPanel);
            app.ListBox_4.Items = {'Point 1', 'Point 2'};
            app.ListBox_4.Multiselect = 'on';
            app.ListBox_4.Value = {'Point 1'};
            app.DropDown_3 = uidropdown(app.ContactPointsPanel);
            app.DropDown_3.Items = {'Solid 1', 'Solid 2'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_3.Value = 'Solid 1';
            app.ListBox_3 = uilistbox(app.ContactPointsPanel);
            app.ListBox_3.Items = {'Point 1', 'Point 2'};
            app.ListBox_3.Multiselect = 'on';
            app.ListBox_3.Value = {'Point 1'};
            app.DropDown_2 = uidropdown(app.ContactPointsPanel);
            app.DropDown_2.Items = {'Solid 1', 'Solid 2'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_2.Value = 'Solid 1';
            app.ListBox_2 = uilistbox(app.ContactPointsPanel);
            app.ListBox_2.Items = {'Point 1', 'Point 2'};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.Value = {'Point 1'};
            app.DropDown_1 = uidropdown(app.ContactPointsPanel);
            app.DropDown_1.Items = {'Solid 1', 'Solid 2'};
            app.DropDown_1.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_1.Value = 'Solid 1';
            app.ListBox_1 = uilistbox(app.ContactPointsPanel);
            app.ListBox_1.Items = {'Point 1', 'Point 2'};
            app.ListBox_1.Multiselect = 'on';
            app.ListBox_1.Value = {'Point 1'};
            app.AddButton = uibutton(app.ContactPointsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.ContactPointsPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.AdditionalmarkersCheckBox = uicheckbox(app.ExternalforcespredictionoptionsUIFigure);
            app.AdditionalmarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdditionalmarkersCheckBoxValueChanged, true);
            app.AdditionalmarkersCheckBox.Text = 'Additional markers';
            % Show the figure after all components are created
            app.ExternalforcespredictionoptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionsPrediction(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExternalforcespredictionoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExternalforcespredictionoptionsUIFigure)
        end
    end
end