classdef OptionsPredictionThreshold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptionsPredictionThresholdUIFigure  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        OKButton                  matlab.ui.control.Button
        PositionthresholdmLabel   matlab.ui.control.Label
        EditField_2               matlab.ui.control.NumericEditField
        VelocitythresholdmsLabel  matlab.ui.control.Label
        EditField_3               matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp
        AnalysisParameters
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            app.EditField_2.Value = OldAnalysisParameters.Prediction.PositionThreshold;
            app.EditField_3.Value = OldAnalysisParameters.Prediction.VelocityThreshold;
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            OldAnalysisParameters.Prediction.PositionThreshold = app.EditField_2.Value;
            OldAnalysisParameters.Prediction.VelocityThreshold = app.EditField_3.Value;
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end

    end
    
    methods (Access = public)
        
        function SaveAnalysisParameters(app,NewAnalysisParameters)
            app.AnalysisParameters = NewAnalysisParameters;
        end
            
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, OldAnalysisParameters)
            app.CallingApp = mainapp;
            % Actualisation des paramètres
            ActuateApp(app,OldAnalysisParameters);
            app.AnalysisParameters = OldAnalysisParameters;
        end

        % Close request function: OptionsPredictionThresholdUIFigure
        function OptionsPredictionThresholdUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveAnalysisParameters(app.CallingApp,app.AnalysisParameters);
            app.OptionsPredictionThresholdUIFigureCloseRequest;
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function DropDown_1ValueChanged(app, event)
          
        end

        % Callback function
        function AddButtonPushed(app, event)
            
        end

        % Callback function
        function DeleteButtonPushed(app, event)
            
        end

        % Callback function
        function AdditionalmarkersCheckBoxValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptionsPredictionThresholdUIFigure = uifigure('Visible', 'off');
            app.OptionsPredictionThresholdUIFigure.Name = 'External forces prediction options';
            app.OptionsPredictionThresholdUIFigure.CloseRequestFcn = createCallbackFcn(app, @OptionsPredictionThresholdUIFigureCloseRequest, true);
            app.Panel = uipanel(app.OptionsPredictionThresholdUIFigure);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.PositionthresholdmLabel = uilabel(app.OptionsPredictionThresholdUIFigure);
            app.PositionthresholdmLabel.Text = 'Position threshold (m)';
            app.EditField_2 = uieditfield(app.OptionsPredictionThresholdUIFigure, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.Value = 0.05;
            app.VelocitythresholdmsLabel = uilabel(app.OptionsPredictionThresholdUIFigure);
            app.VelocitythresholdmsLabel.Text = 'Velocity threshold (m/s)';
            app.EditField_3 = uieditfield(app.OptionsPredictionThresholdUIFigure, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.Value = 0.8;
            % Show the figure after all components are created
            app.OptionsPredictionThresholdUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionsPredictionThreshold(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptionsPredictionThresholdUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptionsPredictionThresholdUIFigure)
        end
    end
end